classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        calculatiprobabilitatilefiecareiintersectiiButton  matlab.ui.control.Button
        AlegetilabirintuldoritDropDownLabel  matlab.ui.control.Label
        AlegetilabirintuldoritDropDown  matlab.ui.control.DropDown
        Panel                           matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
    end


    properties (Access = public)
        ximp; % Description
    end


    methods (Access = private)

        % Value changed function: AlegetilabirintuldoritDropDown
        function AlegetilabirintuldoritDropDownValueChanged(app, event)
            value = app.AlegetilabirintuldoritDropDown.Value;
            if(strcmp(value,'lab1'))
                label='lab1.png';
            else
                if(strcmp(value,'lab2'))
                    label='lab2.png';
                else
                    if(strcmp(value,'lab3'))
                        label='lab3.png';
                    else
                        if(strcmp(value,'lab4'))
                            label='lab4.png';
                        end
                    end
                end
            end
      
            imgdisplay=imread('lab3ss.png');
             ax = uiaxes(app.UIFigure,'Position',[278,165,349,285]);
            image(imgdisplay,'Parent',ax);    
            set(ax,'visible','off');
            
img=imread(label);
img=im2bw(img);
[m,n]=size(img);
k=1;
p=1;
l=1;
s=1;
for i=4:m-3
    for j=4:n-3
        if(isitint(img,i,j)==1)
            x(k)=i;
            y(k)=j;
            k=k+1;
        end
        if(isittr(img,i,j)==1)
            v(p)=i;
            w(p)=j;
            p=p+1;
        end
        if(isitend(img,i,j)==1)
            e(l)=i;
            f(l)=j;
            l=l+1;
        end
        if(isitcor(img,i,j)==1)
            c(s)=i;
            d(s)=j;
            s=s+1;
        end
    end
end
ad=zeros(length(x),length(x)+2);
%pas1
for i=1:length(x)
    for j=1:length(x)
        war=0;
        if(y(i)==y(j)&&i~=j)
            if(x(i)<x(j))
                for k=1:length(x)
                    if(x(k)<x(j)&&x(k)>x(i)&&y(k)==y(i))
                        war=1;
                    end
                end
            else
                if(x(i)>x(j))
                     for k=1:length(x)
                         if(x(k)>x(j)&&x(k)<x(i)&&y(k)==y(i))
                             war=1;
                         end
                     end
                end
            end
            if(war==0)
            ad(i,j)=1;
            end
        end
        
        if(x(i)==x(j)&&i~=j)
            if(y(i)<y(j))
                for k=1:length(x)
                    if(y(k)<y(j)&&y(k)>y(i)&&x(k)==x(i))
                        war=1;
                    end
                end
            else
                if(y(i)>y(j))
                    for k=1:length(x)
                        if(y(k)>y(j)&&y(k)<y(i)&&x(k)==x(i))
                            war=1;
                        end
                    end
                end
            end
            if(war==0)
            ad(i,j)=1;
            end
        end   
    end
end

%pas2

for l=1:length(v)    
    for i=1:length(x)
        war=0;
        if(x(i)==v(l))
            if(y(i)<w(l))
                for k=1:length(x)
                    if(y(k)<w(l)&&y(k)>y(i)&&x(k)==x(i))
                      war=1;
                    end
                end
            else
                if(y(i)>w(l))
                for k=1:length(x)
                    if(y(k)>w(l)&&y(k)<y(i)&&x(k)==x(i))
                       war=1;
                    end  
                end
                end
            end
            
            if(war==0)
                    ad(i,length(x)+2)=1;
            end
        end
        if(y(i)==w(l))
            if(x(i)<v(l))
                for k=1:length(x)
                    if(x(k)<v(l)&&x(k)>x(i)&&y(k)==y(i))
                     
                      war=1;
                    end
                end
            else
                if(x(i)>v(l))
                for k=1:length(x)
                    if(x(k)<x(i)&&x(k)>v(l)&&y(k)==y(i))
       
                      war=1;
                    end
                end
                end
            end
            if(war==0)
            ad(i,length(x)+2)=1;
            end
        end
    end
end

%pas3
for l=1:length(e)    
    for i=1:length(x)
        war=0;
        if(x(i)==e(l))
            if(y(i)<f(l))
                for k=1:length(x)
                    if(y(k)<f(l)&&y(k)>y(i)&&x(k)==x(i))
                      war=1;
                    end
                end
            else
                if(y(i)>f(l))
                for k=1:length(x)
                    if(y(k)>f(l)&&y(k)<y(i)&&x(k)==x(i))
                       war=1;
                    end  
                end
                end
            end
            
            if(war==0)
                    ad(i,length(x)+1)=1;
            end
        end
        if(y(i)==f(l))
            if(x(i)<e(l))
                for k=1:length(x)
                    if(x(k)<e(l)&&x(k)>x(i)&&y(k)==y(i))
                     
                      war=1;
                    end
                end
            else
                if(x(i)>e(l))
                for k=1:length(x)
                    if(x(k)<x(i)&&x(k)>e(l)&&y(k)==y(i))
       
                      war=1;
                    end
                end
                end
            end
            if(war==0)
            ad(i,length(x)+1)=1;
            end
        end
    end
end

%pas4

for i=1:length(x)
   for j=1:length(x)
       for k=1:length(c)
           war=0;
           if(x(j)==c(k)&&y(i)==d(k))
                if(y(j)>d(k))
                    for l=1:length(x)
                        if(y(l)>d(k)&&y(l)<y(j)&&x(l)==x(j))
                            war=1;
                        end
                    end
                else
                    if(y(j)<d(k))
                        for l=1:length(x)
                            if(y(l)<d(k)&&y(l)>y(j)&&x(l)==x(j))
                                war=1;
                            end
                        end
                    end
                end
                if(x(i)>c(k))
                    for l=1:length(x)
                        if(x(l)>c(k)&&x(l)<x(i)&&y(l)==y(i))
                            war=1;
                        end
                    end
                else
                    if(x(i)<c(k))
                        for l=1:length(x)
                            if(x(l)<c(k)&&x(l)>x(i)&&y(l)==y(i))
                                war=1;
                            end
                        end
                    end
                end
                if(war==0&&ad(i,j)==0)
                    ad(i,j)=1;
                end
                    
           end
           if(x(i)==c(k)&&y(j)==d(k))
               if(y(i)>d(k))
                   for l=1:length(x)
                       if(y(l)>d(k)&&y(l)<y(i)&&x(l)==x(i))
                           war =1;
                       end
                   end
               else
                   if(y(i)<d(k))
                       for l=1:length(x)
                           if(y(l)<d(k)&&y(l)>y(i)&&x(l)==x(i))
                               war=1;
                           end
                       end
                   end
               end
               if(x(j)>c(k))
                   for l=1:length(x)
                       if(x(l)>c(k)&&x(l)<x(j)&&y(l)==y(j))
                           war=1;
                       end
                   end
               else
                   if(x(j)<c(k))
                       for l=1:length(x)
                           if(x(l)<c(k)&&x(l)>x(j)&&y(l)==y(j))
                               war=1;
                           end
                       end
                   end
               end
               if(war==0&&ad(i,j)==0)
                    ad(i,j)=1;
               end
           end
       end
   end
end

[a,b]=matcar(ad);
aa=a;
n=length(a);
for k=1:n-1
    for i=k+1:n
        u(i,k)=a(i,k)/a(k,k);
    end
    for i=k+1:n
        for j=k:n
            a(i,j)=a(i,j)-u(i,k)*a(k,j);
        end
    end
end

xi=a*inv(aa)*b;
for i=n:-1:1
    for j=i+1:n
        xi(i)=xi(i)-a(i,j)*xi(j);
    end
    xi(i)=xi(i)/a(i,i);
end

 
    app.ximp=xi;

        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
           
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            
         
        end

        % Button pushed function: 
        % calculatiprobabilitatilefiecareiintersectiiButton
        function calculatiprobabilitatilefiecareiintersectiiButtonPushed(app, event)
          set(app.UITable, 'Data', num2cell(app.ximp) );
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.calculatiprobabilitatilefiecareiintersectiiButton = uibutton(app.UIFigure, 'push');
            app.calculatiprobabilitatilefiecareiintersectiiButton.ButtonPushedFcn = createCallbackFcn(app, @calculatiprobabilitatilefiecareiintersectiiButtonPushed, true);
            app.calculatiprobabilitatilefiecareiintersectiiButton.Text = 'calculati probabilitatile fiecarei intersectii';
            app.AlegetilabirintuldoritDropDownLabel = uilabel(app.UIFigure);
            app.AlegetilabirintuldoritDropDownLabel.Text = 'Alegeti labirintul dorit';
            app.AlegetilabirintuldoritDropDown = uidropdown(app.UIFigure);
            app.AlegetilabirintuldoritDropDown.Items = {'lab1', 'lab2', 'lab3', 'lab4'};
            app.AlegetilabirintuldoritDropDown.ValueChangedFcn = createCallbackFcn(app, @AlegetilabirintuldoritDropDownValueChanged, true);
            app.AlegetilabirintuldoritDropDown.Value = 'lab1';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'vector probabilitati'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end